classdef gaitDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DefineDutyCycleCheckBox         matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        DutycycleEditField              matlab.ui.control.EditField
        DutycycleEditFieldLabel         matlab.ui.control.Label
        EndstancepointEditField         matlab.ui.control.EditField
        EndstancepointEditFieldLabel    matlab.ui.control.Label
        StartstancepointEditField       matlab.ui.control.EditField
        StartstancepointEditFieldLabel  matlab.ui.control.Label
        ofpointsinTrajectoryEditField   matlab.ui.control.NumericEditField
        ofpointsinTrajectoryEditFieldLabel  matlab.ui.control.Label
        LoadTrajectoryEditField         matlab.ui.control.EditField
        LoadTrajectoryEditFieldLabel    matlab.ui.control.Label
        TrajectorynameEditField         matlab.ui.control.EditField
        TrajectorynameEditFieldLabel    matlab.ui.control.Label
        L2EditField                     matlab.ui.control.NumericEditField
        L2EditFieldLabel                matlab.ui.control.Label
        L1EditField                     matlab.ui.control.NumericEditField
        L1EditFieldLabel                matlab.ui.control.Label
        OriginyEditField                matlab.ui.control.NumericEditField
        OriginyEditFieldLabel           matlab.ui.control.Label
        OriginxEditField                matlab.ui.control.NumericEditField
        OriginxEditFieldLabel           matlab.ui.control.Label
        yvalueSlider                    matlab.ui.control.Slider
        yvalueSliderLabel               matlab.ui.control.Label
        ofpointsEditField               matlab.ui.control.NumericEditField
        ofpointsEditFieldLabel          matlab.ui.control.Label
        TotalTimesEditField             matlab.ui.control.NumericEditField
        TotalTimesEditFieldLabel        matlab.ui.control.Label
        TrajectoryGeneratorLabel        matlab.ui.control.Label
        EditpointDropDown               matlab.ui.control.DropDown
        EditpointDropDownLabel          matlab.ui.control.Label
        LoadTrajectoryButton            matlab.ui.control.Button
        SaveTrajectoryButton            matlab.ui.control.Button
        xvalueSlider                    matlab.ui.control.Slider
        xvalueSliderLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        time_vec; % vector of time variables
        final_time = 1; % Description
        xvals; % Description
        yvals; % Description
        
        numPointsOut = 500; % number of trajectory points in output
        xvalsFlight % The defining points that are part of flight
        xvalsStance % Description
        yvalsFlight % Description
        yvalsStance % Description
    end
    
    methods (Access = private)
        
        function plotVals(app)
            cla(app.UIAxes)
            activePoint = str2num(app.EditpointDropDown.Value);
            app.xvals(end) = app.xvals(1);
            app.yvals(end) = app.yvals(1);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.xvals,app.yvals,'bo')
            app.plotWorkspace()
            
            [spl,tTraj,traj] = app.GetSpline;

            plot(app.UIAxes,traj(1,:),traj(2,:))
            plot(app.UIAxes,app.xvals(activePoint),app.yvals(activePoint),'r*')
            hold(app.UIAxes,'off')
        end
        
        function plotWorkspace(app)
            theta = 0:0.1:2*pi;
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            wsxmin = app.OriginxEditField.Value + (L2-L1)*sin(theta);
            wsymin = app.OriginyEditField.Value + (L2-L1)*cos(theta);
            wsxmax = app.OriginxEditField.Value + (L1+L2)*sin(theta);
            wsymax = app.OriginyEditField.Value + (L1+L2)*cos(theta);
            
            plot(app.UIAxes,wsxmin,wsymin,'r-',wsxmax,wsymax,'r-')
        end
        
        function results = InvKin(app,spline)
            x = spline(1,:);
            y = spline(2,:);
            
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            R = sqrt(x.^2+y.^2);
            theta = atan2(y,x);

            alpha = acos((L1^2-L2^2+R.^2)./(2*L1*R));
            M1 = (theta+alpha)/(2*pi);
            M2 = (theta-alpha)/(2*pi);
            
            results = [M1.' M2.'];

        end
        
        function [spl,tTraj,traj] = GetSpline(app)
            
            if app.DefineDutyCycleCheckBox.Value == 1
                stanceStart = str2num(app.StartstancepointEditField.Value);
                stanceEnd = str2num(app.EndstancepointEditField.Value);
                
                duty = str2num(app.DutycycleEditField.Value)/100;

                if stanceStart<stanceEnd
                    numDesignPts = app.ofpointsEditField.Value;
                    numStanceSegs = stanceEnd-stanceStart;
                    numFlightSegs = numDesignPts - numStanceSegs;
                    numFlightSegsBefore = stanceStart - 1;
                    numFlightSegsAfter = numDesignPts - stanceEnd + 1;
% 
                    numStancePts = floor((numStanceSegs/numDesignPts)*app.numPointsOut);
                    numFlightPts = app.numPointsOut - numStancePts;
                    numFlightPts1 = floor((numFlightSegs/numDesignPts)*numFlightPts);
                    numFlightPts2 = numFlightPts - numFlightPts1;

                    stanceStartPts = floor((numFlightSegsBefore/numDesignPts)*app.numPointsOut);

                    xyStance = [app.xvals(stanceStart:stanceEnd).' app.yvals(stanceStart:stanceEnd).'].';

                    spl = csape(app.time_vec(stanceStart:stanceEnd),xyStance);
                    tTraj = linspace(app.time_vec(stanceStart),app.time_vec(stanceEnd),numStancePts);
                    stanceTraj = fnval(spl,tTraj);

                    xFlight = [app.xvals(stanceEnd+1:end) app.xvals(1:stanceStart)];
                    yFlight = [app.yvals(stanceEnd+1:end) app.yvals(1:stanceStart)];
                    xyFlight = [xFlight.' yFlight.'].';
                    timeFlight = [app.time_vec(stanceEnd+1:end) app.time_vec(1:stanceStart)];

                    spl = csape(timeFlight,xyFlight);
                    tTraj1 = linspace(app.time_vec(stanceEnd+1),app.time_vec(end),numFlightPts1);
                    tTraj2 = linspace(app.time_vec(1),app.time_vec(stanceStart),numFlightPts2);
                    tTraj = [tTraj1 tTraj2];

                    flightTraj = fnval(spl,tTraj);

%                     traj = [stanceTraj flightTraj];
                    traj = stanceTraj;

                end


            else

                xy = [app.xvals.' app.yvals.'].';
                spl = csape(app.time_vec,xy, 'periodic');
                tTraj = linspace(app.time_vec(1),app.time_vec(end),app.numPointsOut);
                traj = fnval(spl,tTraj);
            end
        end
        
        function results = rad2rev(app,theta)
            results = theta./(2*pi);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.plotWorkspace()
            app.xvalueSlider.Limits = app.UIAxes.XLim;
            app.yvalueSlider.Limits = app.UIAxes.YLim;
            
        end

        % Value changed function: xvalueSlider
        function xvalueSliderValueChanged(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.xvalueSlider.Limits = app.UIAxes.XLim;
            app.yvalueSlider.Limits = app.UIAxes.YLim;
        end

        % Value changed function: ofpointsEditField
        function ofpointsEditFieldValueChanged(app, event)
            value = app.ofpointsEditField.Value;
            app.time_vec = linspace(0,app.final_time,value+1);
            indices = 1:length(app.time_vec);

            app.EditpointDropDown.Items = strsplit(num2str(indices));
            app.xvals = app.L2EditField.Value*sin(app.time_vec.*pi./app.final_time);
            app.yvals = app.L2EditField.Value*cos(app.time_vec.*pi./app.final_time);
            
            app.xvals(end) = app.xvals(1);
            app.yvals(end) = app.yvals(1);
            
            
            app.plotVals();
        end

        % Value changed function: TotalTimesEditField
        function TotalTimesEditFieldValueChanged(app, event)
            value = app.TotalTimesEditField.Value;
            app.final_time = value;
        end

        % Callback function: L1EditField, xvalueSlider
        function xvalueSliderValueChanging(app, event)
            changingValue = event.Value;
            index = app.EditpointDropDown.Value;
            index = str2num(index);
            app.xvals(index) = changingValue;
            app.plotVals()
        end

        % Value changing function: yvalueSlider
        function yvalueSliderValueChanging(app, event)
            changingValue = event.Value;
            index = app.EditpointDropDown.Value;
            index = str2num(index);
            app.yvals(index) = changingValue;
            app.plotVals()
        end

        % Value changed function: EditpointDropDown
        function EditpointDropDownValueChanged(app, event)
            value = app.EditpointDropDown.Value;
            index = str2num(value);
            app.xvalueSlider.Value = app.xvals(index);
            app.yvalueSlider.Value = app.yvals(index);
            app.plotVals()
        end

        % Button pushed function: SaveTrajectoryButton
        function SaveTrajectoryButtonPushed(app, event)
            name = app.TrajectorynameEditField.Value;
            [~,~,traj] = app.GetSpline;

            trajMirrored = [-traj(1,:); traj(2,:)];
            rightMotorSpace = app.InvKin(traj);
            leftMotorSpace = app.InvKin(trajMirrored);

            index = 1:app.numPointsOut;
            
            footSpacedata = [index(:) traj.'];
            motorSpaceData = [index(:) rightMotorSpace leftMotorSpace];
            xvals = app.xvals;
            yvals = app.yvals;
            time_vec = app.time_vec;
            
            save(name,'xvals','yvals','time_vec');
            writematrix(footSpacedata,strcat(name,'FootSpace','.csv'));
            writematrix(motorSpaceData,strcat(name,'MotorSpace','.csv'));
            
        end

        % Button pushed function: LoadTrajectoryButton
        function LoadTrajectoryButtonPushed(app, event)
            name = app.LoadTrajectoryEditField.Value;
            load(strcat(name,'.mat'));
            app.xvals = xvals;
            app.yvals = yvals;
            app.time_vec = time_vec;
            
            app.ofpointsEditField.Value = length(time_vec)-1;
            indices = 1:length(app.time_vec);
            app.EditpointDropDown.Items = strsplit(num2str(indices));
            app.plotVals
        end

        % Value changed function: ofpointsinTrajectoryEditField
        function ofpointsinTrajectoryEditFieldValueChanged(app, event)
            value = app.ofpointsinTrajectoryEditField.Value;
            app.numPointsOut = value;
        end

        % Callback function
        function L1EditFieldValueChanged(app, event)
            value = app.L1EditField.Value;
            app.plotWorkspace
            
        end

        % Value changed function: L2EditField
        function L2EditFieldValueChanged(app, event)
            value = app.L2EditField.Value;
            app.plotWorkspace
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.xvalueSliderLabel = uilabel(app.UIFigure);
            app.xvalueSliderLabel.Text = 'x value';
            app.xvalueSlider = uislider(app.UIFigure);
            app.xvalueSlider.Limits = [0 1];
            app.xvalueSlider.ValueChangedFcn = createCallbackFcn(app, @xvalueSliderValueChanged, true);
            app.xvalueSlider.ValueChangingFcn = createCallbackFcn(app, @xvalueSliderValueChanging, true);
            app.SaveTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.SaveTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrajectoryButtonPushed, true);
            app.SaveTrajectoryButton.Text = 'Save Trajectory';
            app.LoadTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.LoadTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoryButtonPushed, true);
            app.LoadTrajectoryButton.Text = 'Load Trajectory';
            app.EditpointDropDownLabel = uilabel(app.UIFigure);
            app.EditpointDropDownLabel.Text = 'Edit point';
            app.EditpointDropDown = uidropdown(app.UIFigure);
            app.EditpointDropDown.ValueChangedFcn = createCallbackFcn(app, @EditpointDropDownValueChanged, true);
            app.TrajectoryGeneratorLabel = uilabel(app.UIFigure);
            app.TrajectoryGeneratorLabel.Text = 'Trajectory Generator';
            app.TotalTimesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalTimesEditFieldLabel.Text = 'Total Time (s)';
            app.TotalTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalTimesEditField.Limits = [0 Inf];
            app.TotalTimesEditField.ValueChangedFcn = createCallbackFcn(app, @TotalTimesEditFieldValueChanged, true);
            app.TotalTimesEditField.Value = 1;
            app.ofpointsEditFieldLabel = uilabel(app.UIFigure);
            app.ofpointsEditFieldLabel.Text = '# of points';
            app.ofpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofpointsEditField.ValueChangedFcn = createCallbackFcn(app, @ofpointsEditFieldValueChanged, true);
            app.yvalueSliderLabel = uilabel(app.UIFigure);
            app.yvalueSliderLabel.Text = 'y value';
            app.yvalueSlider = uislider(app.UIFigure);
            app.yvalueSlider.Limits = [0 1];
            app.yvalueSlider.ValueChangingFcn = createCallbackFcn(app, @yvalueSliderValueChanging, true);
            app.OriginxEditFieldLabel = uilabel(app.UIFigure);
            app.OriginxEditFieldLabel.Text = 'Origin x';
            app.OriginxEditField = uieditfield(app.UIFigure, 'numeric');
            app.OriginyEditFieldLabel = uilabel(app.UIFigure);
            app.OriginyEditFieldLabel.Text = 'Origin y';
            app.OriginyEditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @xvalueSliderValueChanging, true);
            app.L1EditField.Value = 66;
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
            app.L2EditField.Value = 180;
            app.TrajectorynameEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectorynameEditFieldLabel.Text = 'Trajectory name';
            app.TrajectorynameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadTrajectoryEditFieldLabel = uilabel(app.UIFigure);
            app.LoadTrajectoryEditFieldLabel.Text = 'Load Trajectory';
            app.LoadTrajectoryEditField = uieditfield(app.UIFigure, 'text');
            app.ofpointsinTrajectoryEditFieldLabel = uilabel(app.UIFigure);
            app.ofpointsinTrajectoryEditFieldLabel.Text = '# of points in Trajectory';
            app.ofpointsinTrajectoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofpointsinTrajectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ofpointsinTrajectoryEditFieldValueChanged, true);
            app.ofpointsinTrajectoryEditField.Value = 1000;
            app.StartstancepointEditFieldLabel = uilabel(app.UIFigure);
            app.StartstancepointEditFieldLabel.Text = 'Start stance point';
            app.StartstancepointEditField = uieditfield(app.UIFigure, 'text');
            app.EndstancepointEditFieldLabel = uilabel(app.UIFigure);
            app.EndstancepointEditFieldLabel.Text = 'End stance point';
            app.EndstancepointEditField = uieditfield(app.UIFigure, 'text');
            app.DutycycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutycycleEditFieldLabel.Text = 'Duty cycle';
            app.DutycycleEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.DefineDutyCycleCheckBox = uicheckbox(app.UIFigure);
            app.DefineDutyCycleCheckBox.Text = 'Define Duty Cycle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gaitDesignApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end